classdef app_Geometry_and_Volume_Calculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HdS2Hs                          matlab.ui.control.NumericEditField
        H_dS_2H_sLabel                  matlab.ui.control.Label
        HdS3Hs                          matlab.ui.control.NumericEditField
        H_dS_3H_sLabel                  matlab.ui.control.Label
        RdS2Rs                          matlab.ui.control.NumericEditField
        R_dS_2R_sLabel                  matlab.ui.control.Label
        VdS3Vs                          matlab.ui.control.NumericEditField
        V_dS_3V_sLabel                  matlab.ui.control.Label
        VdS2Vs                          matlab.ui.control.NumericEditField
        V_dS_2V_sLabel                  matlab.ui.control.Label
        RdS3Rs                          matlab.ui.control.NumericEditField
        R_dS_3R_sLabel                  matlab.ui.control.Label
        V3V1                            matlab.ui.control.NumericEditField
        V_3V_1Label                     matlab.ui.control.Label
        V2V1                            matlab.ui.control.NumericEditField
        V_2V_1Label                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        StableState1Tab                 matlab.ui.container.Tab
        Rs                              matlab.ui.control.NumericEditField
        R_sLabel                        matlab.ui.control.Label
        Hs                              matlab.ui.control.NumericEditField
        H_sLabel                        matlab.ui.control.Label
        Vs                              matlab.ui.control.NumericEditField
        V_sLabel                        matlab.ui.control.Label
        V1                              matlab.ui.control.NumericEditField
        V_1Label                        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        StableState2Tab                 matlab.ui.container.Tab
        RdS2                            matlab.ui.control.NumericEditField
        R_dS_2Label                     matlab.ui.control.Label
        HdS2                            matlab.ui.control.NumericEditField
        H_dS_2Label                     matlab.ui.control.Label
        VdS2                            matlab.ui.control.NumericEditField
        V_dS_2Label                     matlab.ui.control.Label
        V2                              matlab.ui.control.NumericEditField
        V_2Label                        matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        StableState3Tab                 matlab.ui.container.Tab
        VdS3                            matlab.ui.control.NumericEditField
        V_dS_3Label                     matlab.ui.control.Label
        RdS3                            matlab.ui.control.NumericEditField
        R_dS_3Label                     matlab.ui.control.Label
        V3                              matlab.ui.control.NumericEditField
        V_3Label                        matlab.ui.control.Label
        HdS3                            matlab.ui.control.NumericEditField
        H_dS_3Label                     matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        OutputFoldButton                matlab.ui.control.Button
        d_1RSlider                      matlab.ui.control.Slider
        d_1RLabel                       matlab.ui.control.Label
        phi_1circLabel                  matlab.ui.control.Label
        BlueMonostableLabel             matlab.ui.control.Label
        RedBistableLabel                matlab.ui.control.Label
        MiuraLabel                      matlab.ui.control.Label
        BlueBistableLabel               matlab.ui.control.Label
        RedTristableLabel               matlab.ui.control.Label
        KreslingLabel                   matlab.ui.control.Label
        Flatfoldable                    matlab.ui.control.EditField
        FlatfoldableEditFieldLabel      matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        NumberofstablestatesLabel       matlab.ui.control.Label
        upperbound                      matlab.ui.control.NumericEditField
        lowerbound                      matlab.ui.control.NumericEditField
        Step4OutputgeometryandcomputevolumeLabel  matlab.ui.control.Label
        Step3ComputenumberofstablestatesLabel  matlab.ui.control.Label
        Step2SelectacreasepatternLabel  matlab.ui.control.Label
        Step1ChoosenandplotdesignspaceLabel  matlab.ui.control.Label
        Note                            matlab.ui.control.EditField
        NoteEditFieldLabel              matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        NumberofSolutions               matlab.ui.control.NumericEditField
        m                               matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        d1R                             matlab.ui.control.NumericEditField
        R                               matlab.ui.control.NumericEditField
        RmLabel                         matlab.ui.control.Label
        phi2                            matlab.ui.control.NumericEditField
        phi_2circLabel                  matlab.ui.control.Label
        phi1                            matlab.ui.control.NumericEditField
        n                               matlab.ui.control.NumericEditField
        nLabel                          matlab.ui.control.Label
        GeometryandVolumeCalculationforMiuraandKreslingBellowsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            n = app.n.Value;
            x1=linspace(pi/n,pi/2,100);          
            y1=x1-pi/n;
            x2=linspace(pi/4+pi/(2*n),pi/2,100);
            y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
            y3=zeros(1,100);
            x3=ones(1,100);
            plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
            hold(app.UIAxes,'on')            
            plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
            hold(app.UIAxes,'on')  
            fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
            hold(app.UIAxes,'on') 
            fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
            hold(app.UIAxes,'on') 
            phi1Deg = app.phi1.Value;
            phi2Deg = app.phi2.Value;
            scatter(app.UIAxes,phi1Deg,phi2Deg,'MarkerFaceColor','y','LineWidth',2);
            app.d1R.Value=2*pi/n;
            
                      %Compute Stability and Output Geometry
 phi1Deg = app.phi1.Value;        
 phi2Deg = app.phi2.Value;               
 %m = app.m.Value;            
 n = app.n.Value; 
 
 
 [solution] = calculatesolution(phi1Deg,phi2Deg,n);
            %%%preclude flat-foldable
      if phi1Deg == phi2Deg +180/n 
        %  disp('Flatfoldable');
           % noWrite = 1;
            solution = 2;   
             app.Flatfoldable.Value= 'Yes'
      else
            app.Flatfoldable.Value= 'No'
      end

     %%Assign solution to the output button
     app.NumberofSolutions.Value=solution;
     %For Kresling add stable state 3
     value = app.Switch.Value;
            %Miura or Kresling
            if  strcmp(value, 'Kresling') && solution~=0
                app.NumberofSolutions.Value=solution+1;
            end
            
            %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
           end
        end

        % Callback function
        function ButtonPushed(app, event)
           
         
        end

        % Callback function
        function ComputeButtonPushed(app, event)

        end

        % Value changed function: d1R
        function d1RValueChanged(app, event)
          %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
           end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
             value = app.Switch.Value;
             n = app.n.Value;
             app.d1R.Value=0;
             app.lowerbound.Value=0;
             app.upperbound.Value=0;
            %Miura or Kresling
            if strcmp(value, 'Miura')
                app.Lamp.Color='0.00,1.00,0.00';
                app.Note.Value='Please use the slider to define d1/R';        
                app.lowerbound.Value=1.01*pi/n;
                app.upperbound.Value=1.99*pi/n;
                app.d1R.Value=1.01*pi/n;
            elseif strcmp(value, 'Kresling')
                app.Lamp.Color='0.00,0.45,0.74';
                app.Note.Value='d1/R = 2π/n';                               
                app.d1R.Value=2*pi/n;
            end
            
            %Compute Stability and Output Geometry
 phi1Deg = app.phi1.Value;        
 phi2Deg = app.phi2.Value;               
 %m = app.m.Value;            
 n = app.n.Value; 
 
 if phi1Deg*phi2Deg*n~=0
 [solution] = calculatesolution(phi1Deg,phi2Deg,n);
            %%%preclude flat-foldable
      if phi1Deg == phi2Deg +180/n 
        %  disp('Flatfoldable');
           % noWrite = 1;
            solution = 2;   
             app.Flatfoldable.Value= 'Yes'
      else
            app.Flatfoldable.Value= 'No'
      end

     %%Assign solution to the output button
     app.NumberofSolutions.Value=solution;
     %For Kresling add stable state 3
            %Miura or Kresling
            if  strcmp(value, 'Kresling') && solution~=0
                app.NumberofSolutions.Value=solution+1;
            end
 end


 %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Callback function
        function PlotdesignspaceButtonPushed(app, event)
            %Plot design space
            cla(app.UIAxes)
            n = app.n.Value;
            x1=linspace(pi/n,pi/2,100);          
            y1=x1-pi/n;
            x2=linspace(pi/4+pi/(2*n),pi/2,100);
            y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
            y3=zeros(1,100);
            x3=ones(1,100);
            plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
            hold(app.UIAxes,'on')            
            plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
            hold(app.UIAxes,'on')  
            fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
            hold(app.UIAxes,'on') 
            fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
            hold(app.UIAxes,'on') 
            app.d1R.Value=2*pi/n;
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
           
        end

        % Callback function
        function PlotS1ButtonPushed(app, event)

            
        end

        % Callback function
        function PlotS2ButtonPushed(app, event)

        end

        % Callback function
        function PlotS3ButtonPushed(app, event)

        end

        % Value changed function: lowerbound
        function lowerboundValueChanged(app, event)
     
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.n.Value=4;
            cla(app.UIAxes);
            app.Switch.Value='Kresling';
            app.Note.Value='d1/R = 2π/n';
            app.Lamp.Color='0.00,0.45,0.74';
            app.phi1.Value=75;
            app.phi2.Value=32;
            app.m.Value=2;
            app.R.Value=3;
            n=app.n.Value;
            app.d1R.Value=2*pi/n;


            value = app.n.Value;
             %Plot design space
            cla(app.UIAxes)
            n = app.n.Value;
            x1=linspace(pi/n,pi/2,100);          
            y1=x1-pi/n;
            x2=linspace(pi/4+pi/(2*n),pi/2,100);
            y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
%             y3=zeros(1,100);
%             x3=ones(1,100);
            plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
            hold(app.UIAxes,'on')            
            plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
            hold(app.UIAxes,'on')  
            fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
            hold(app.UIAxes,'on') 
            fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
            hold(app.UIAxes,'on') 
            
            value = app.Switch.Value;
            if strcmp(value, 'Miura')
                app.d1R.Value=1.01*pi/n;
            else
                app.d1R.Value=2*pi/n;
            end
            app.lowerbound.Value=1.01*pi/n;
            app.upperbound.Value=1.99*pi/n;
            
            phi1Deg = app.phi1.Value;
            phi2Deg = app.phi2.Value;
            if phi2Deg*phi1Deg==0
            else
                scatter(app.UIAxes,phi1Deg,phi2Deg,'MarkerFaceColor','y','LineWidth',2);
            end            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            mousePoints = get(app.UIFigure,'CurrentPoint');
            pos = app.UIAxes.Position;%the position of figure
            x = mousePoints(1,1);
            y = mousePoints(1,2);
            pt = app.UIAxes.CurrentPoint;
            xp = pt(1,1);
            yp = pt(1,2);            
           if x>=pos(1)&&x<=(pos(1)+pos(3))&&y>=pos(2)&&y<=(pos(2)+pos(4)) 
                phi1 = xp;
                phi2 = yp;
                cla(app.UIAxes);
                n = app.n.Value;
                x1=linspace(pi/n,pi/2,100);
                y1=x1-pi/n;
                x2=linspace(pi/4+pi/(2*n),pi/2,100);
                y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
                y3=zeros(1,100);
                x3=ones(1,100);
                plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
                hold(app.UIAxes,'on')
                plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
                hold(app.UIAxes,'on')                
                value = app.Switch.Value;
                if strcmp(value, 'Miura')
                    app.d1R.Value=1.01*pi/n;
                else
                    app.d1R.Value=2*pi/n;
                end
                scatter(app.UIAxes,phi1 ,phi2,'MarkerFaceColor','y','LineWidth',2);
                %hold(app.UIAxes,'on');
                app.phi1.Value = phi1;
                app.phi2.Value = phi2;              
           end      
           

           %Compute Stability and Output Geometry
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           %m = app.m.Value;
           n = app.n.Value;
           %R = app.R.Value;
           %d1overR = app.d1R.Value;
           %% Judge how many solutions
           % %convert angles
           phi1 = phi1Deg*pi/180;
           phi2 = phi2Deg*pi/180;

           FOLD_count = 0;
           noWrite = 0;
           %Constraints on phi1 and phi2


           if phi1Deg <= 180/n
               %  disp('0 solution');
               noWrite = 1;
               solution = 0;
           end

           if phi1Deg <= phi2Deg
               %  disp('0 solution');
               noWrite = 1;
               solution = 0;
           end

           if phi1Deg <= 180/4+180/(2*n)
               if phi2Deg>0 && phi2Deg<(phi1Deg-180/n)
                   %               x1 = tan(phi2)/tan(phi1);
                   %          x2= tan(pi/n);
                   %          beta1 = 2*atan(1/(2*x2)*(1-x1-(sqrt((x1-1)^2 - 4*x1*x2^2))));
                   %          beta2 = 2*atan(1/(2*x2)*(1-x1+(sqrt((x1-1)^2 - 4*x1*x2^2))));
                   %          beta = beta1;
                   %             %1st solution (S2 more deployed)： beta = beta1
                   %             %2nd solution (S1 more compressed)： beta = beta2
                   %             % alpha is angular deflection from vertical (z-axis)
                   %             %pi-alpha is the opening angle between adjacent bands of folds
                   %            alpha = asin(tan(beta/2)/tan(phi2));
                   %
                   %           %%%The angle deflection from Z axis
                   %             if alpha > 80/180*pi
                   %
                   %             noWrite = 1;
                   %             solution = 0;
                   %             else
                   %   disp('1 solution');

                   noWrite = 0;
                   solution = 1;
                   %   end
               else
                   %   disp('0 solution');
                   noWrite = 1;
                   solution = 0;
               end
           end

           if phi1Deg>180/4+180/(2*n)
               % Boundary of bistable region
               limitangle1=(phi1Deg-180/n);
               limitangle2=(atan(tan(phi1)*(1-sin(pi/n))/(1+sin(pi/n))))/pi*180;
               if phi2Deg>=phi1Deg-180/n && phi2Deg<(atan(tan(phi1)*(1-sin(pi/n))/(1+sin(pi/n))))/pi*180
                   %          x1 = tan(phi2)/tan(phi1);
                   %          x2= tan(pi/n);
                   %          beta1 = 2*atan(1/(2*x2)*(1-x1-(sqrt((x1-1)^2 - 4*x1*x2^2))));
                   %          beta2 = 2*atan(1/(2*x2)*(1-x1+(sqrt((x1-1)^2 - 4*x1*x2^2))));
                   %          beta = beta2;
                   %             %1st solution (S2 more deployed)： beta = beta1
                   %             %2nd solution (S1 more compressed)： beta = beta2
                   %             % alpha is angular deflection from vertical (z-axis)
                   %             %pi-alpha is the opening angle between adjacent bands of folds
                   %            alpha = asin(tan(beta/2)/tan(phi2));
                   %
                   %           %%%The angle deflection from Z axis
                   %             if alpha > 70/180*pi
                   %          %   disp('Flatfoldable');
                   %             noWrite = 1;
                   %             solution = 0;
                   %             else
                   %      disp('2 solutions');
                   solution = 2;
                   %            end
                   %1st solution (S2)： beta = beta1
                   %2nd solution (S1)： beta = beta2
               elseif phi2>0 && phi2<phi1-pi/n
                   %                  x1 = tan(phi2)/tan(phi1);
                   %          x2= tan(pi/n);
                   %          beta1 = 2*atan(1/(2*x2)*(1-x1-(sqrt((x1-1)^2 - 4*x1*x2^2))));
                   %          beta2 = 2*atan(1/(2*x2)*(1-x1+(sqrt((x1-1)^2 - 4*x1*x2^2))));
                   %          beta = beta1;
                   %             %1st solution (S2 more deployed)： beta = beta1
                   %             %2nd solution (S1 more compressed)： beta = beta2
                   %             % alpha is angular deflection from vertical (z-axis)
                   %             %pi-alpha is the opening angle between adjacent bands of folds
                   %            alpha = asin(tan(beta/2)/tan(phi2));
                   %
                   %           %%%The angle deflection from Z axis
                   %             if alpha > 70/180*pi
                   %          %   disp('Flatfoldable');
                   %             noWrite = 1;
                   %             solution = 0;
                   %             else
                   %           %  disp('1 solution');
                   solution = 1;
                   %    end
               elseif  phi2>atan(tan(phi1)*(1-sin(pi/n))/(1+sin(pi/n)))
                   %   disp('0 solution');
                   solution = 0;
               else
                   solution = 0;
               end
           end

           %%%preclude flat-foldable
           if phi1Deg == phi2Deg +180/n
               %  disp('Flatfoldable');
               % noWrite = 1;
               solution = 2;
               app.Flatfoldable.Value= 'Yes'
           else
               app.Flatfoldable.Value= 'No'
           end

           %%Assign solution to the output button
           app.NumberofSolutions.Value=solution;
           %For Kresling add stable state 3
           value = app.Switch.Value;
           %Miura or Kresling
           if  strcmp(value, 'Kresling') && solution~=0
               app.NumberofSolutions.Value=solution+1;
           end
           %             %plot phi1 and phi2 on design map
           %             if phi1Deg*phi2Deg~=0
           %             scatter(app.UIAxes,phi1Deg,phi2Deg,30,"filled");
           %             end


           %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Value changing function: d_1RSlider
        function d_1RSliderValueChanging(app, event)
            value = app.Switch.Value;
            n = app.n.Value;
            if strcmp(value, 'Miura')            
            changingValue = event.Value; 
            %Avoid hitting lower and upper bounds
            if changingValue==0
                changingValue=1;
            elseif changingValue==100
                changingValue=99;
            end
            app.d1R.Value= changingValue/100*(2*pi/n-pi/n)+pi/n;
            %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
            end 
        end

        % Value changed function: n
        function nValueChanged(app, event)
            value = app.n.Value;
             %Plot design space
            cla(app.UIAxes)
            n = app.n.Value;
            x1=linspace(pi/n,pi/2,100);          
            y1=x1-pi/n;
            x2=linspace(pi/4+pi/(2*n),pi/2,100);
            y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
%             y3=zeros(1,100);
%             x3=ones(1,100);
            plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
            hold(app.UIAxes,'on')            
            plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
            hold(app.UIAxes,'on')  
            fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
            hold(app.UIAxes,'on') 
            fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
            hold(app.UIAxes,'on') 
            
            value = app.Switch.Value;
            if strcmp(value, 'Miura')
                app.d1R.Value=1.01*pi/n;
            else
                app.d1R.Value=2*pi/n;
            end
            app.lowerbound.Value=1.01*pi/n;
            app.upperbound.Value=1.99*pi/n;
            
            phi1Deg = app.phi1.Value;
            phi2Deg = app.phi2.Value;
            if phi2Deg*phi1Deg==0
            else
                scatter(app.UIAxes,phi1Deg,phi2Deg,'MarkerFaceColor','y','LineWidth',2);
            end
            
            %Compute Stability and Output Geometry
            if phi1Deg*phi2Deg*n~=0
                [solution] = calculatesolution(phi1Deg,phi2Deg,n);
                %%%preclude flat-foldable
                if phi1Deg == phi2Deg +180/n
                    %  disp('Flatfoldable');
                    % noWrite = 1;
                    solution = 2;
                    app.Flatfoldable.Value= 'Yes'
                else
                    app.Flatfoldable.Value= 'No'
                end

                %%Assign solution to the output button
                app.NumberofSolutions.Value=solution;
                %For Kresling add stable state 3
                value = app.Switch.Value;
                %Miura or Kresling
                if  strcmp(value, 'Kresling') && solution~=0
                    app.NumberofSolutions.Value=solution+1;
                end
                %plot phi1 and phi2 on design map
                cla(app.UIAxes);
                n = app.n.Value;
                x1=linspace(pi/n,pi/2,100);
                y1=x1-pi/n;
                x2=linspace(pi/4+pi/(2*n),pi/2,100);
                y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
                y3=zeros(1,100);
                x3=ones(1,100);
                plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
                hold(app.UIAxes,'on')
                plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
                hold(app.UIAxes,'on')

                scatter(app.UIAxes,phi1Deg,phi2Deg,'MarkerFaceColor','y','LineWidth',2);

            end


 %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        
        end

        % Value changed function: NumberofSolutions
        function NumberofSolutionsValueChanged(app, event)
          
        end

        % Value changed function: phi2
        function phi2ValueChanged(app, event)
            %Compute Stability and Output Geometry
            phi1Deg = app.phi1.Value;
            phi2Deg = app.phi2.Value;
            %m = app.m.Value;
            n = app.n.Value;

            if phi1Deg*phi2Deg*n~=0
                [solution] = calculatesolution(phi1Deg,phi2Deg,n);
                %%%preclude flat-foldable
                if phi1Deg == phi2Deg +180/n
                    %  disp('Flatfoldable');
                    % noWrite = 1;
                    solution = 2;
                    app.Flatfoldable.Value= 'Yes'
                else
                    app.Flatfoldable.Value= 'No'
                end

                %%Assign solution to the output button
                app.NumberofSolutions.Value=solution;
                %For Kresling add stable state 3
                value = app.Switch.Value;
                %Miura or Kresling
                if  strcmp(value, 'Kresling') && solution~=0
                    app.NumberofSolutions.Value=solution+1;
                end
                %plot phi1 and phi2 on design map
                cla(app.UIAxes);
                n = app.n.Value;
                x1=linspace(pi/n,pi/2,100);
                y1=x1-pi/n;
                x2=linspace(pi/4+pi/(2*n),pi/2,100);
                y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
                y3=zeros(1,100);
                x3=ones(1,100);
                plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
                hold(app.UIAxes,'on')
                plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
                hold(app.UIAxes,'on')

                scatter(app.UIAxes,phi1Deg,phi2Deg,'MarkerFaceColor','y','LineWidth',2);

            end
 
 %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Value changing function: Note
        function NoteValueChanging(app, event)

            
        end

        % Callback function
        function ComputeButtonPushed2(app, event)

        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown2(app, event)
            
        end

        % Value changed function: phi1
        function phi1ValueChanged(app, event)
            %Compute Stability and Output Geometry
            phi1Deg = app.phi1.Value;
            phi2Deg = app.phi2.Value;
            %m = app.m.Value;
            n = app.n.Value;

            if phi1Deg*phi2Deg*n~=0
                [solution] = calculatesolution(phi1Deg,phi2Deg,n);
                %%%preclude flat-foldable
                if phi1Deg == phi2Deg +180/n
                    %  disp('Flatfoldable');
                    % noWrite = 1;
                    solution = 2;
                    app.Flatfoldable.Value= 'Yes'
                else
                    app.Flatfoldable.Value= 'No'
                end

                %%Assign solution to the output button
                app.NumberofSolutions.Value=solution;
                %For Kresling add stable state 3
                value = app.Switch.Value;
                %Miura or Kresling
                if  strcmp(value, 'Kresling') && solution~=0
                    app.NumberofSolutions.Value=solution+1;
                end
                %plot phi1 and phi2 on design map
                cla(app.UIAxes);
                n = app.n.Value;
                x1=linspace(pi/n,pi/2,100);
                y1=x1-pi/n;
                x2=linspace(pi/4+pi/(2*n),pi/2,100);
                y2=atan(tan(x2)*(1-sin(pi/n))/(1+sin(pi/n)));
                y3=zeros(1,100);
                x3=ones(1,100);
                plot(app.UIAxes,x1/pi*180,y1/pi*180, 'b');
                hold(app.UIAxes,'on')
                plot(app.UIAxes,x2/pi*180,y2/pi*180, 'r');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x1/pi*180, 90],[y1/pi*180,0],'b');
                hold(app.UIAxes,'on')
                fill(app.UIAxes,[x2/pi*180, 90],[y2/pi*180,90-180/n],'r');
                hold(app.UIAxes,'on')

                scatter(app.UIAxes,phi1Deg,phi2Deg,'MarkerFaceColor','y','LineWidth',2);

            end


  %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Value changed function: R
        function RValueChanged(app, event)
            %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Value changed function: m
        function mValueChanged(app, event)
       %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;
           %Do not Output .Fold file
           Output=0;
           %
           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Button pushed function: OutputFoldButton
        function OutputFoldButtonPushed(app, event)
  
           %Output .Fold file
           Output=1;
           %
           %%%%%%%%%%%Step4
           cla(app.UIAxes2)
           cla(app.UIAxes4)
           cla(app.UIAxes3)
           app.Hs.Value=0;
           app.Rs.Value=0;           
           app.Vs.Value=0;
           app.V1.Value=0;
           app.HdS2.Value=0;
           app.RdS2.Value=0;
           app.VdS2.Value=0;
           app.V2.Value=0;
           app.HdS3.Value=0 ;
           app.RdS3.Value=0;
           app.VdS3.Value=0;
           app.V3.Value=0;
           app.RdS2Rs.Value=0;
           app.RdS3Rs.Value=0;
           app.VdS3Vs.Value=0;
           app.VdS2Vs.Value=0;
           app.V3V1.Value=0;
           app.V2V1.Value=0;
           app.HdS2Hs.Value=0;
           app.HdS3Hs.Value=0;

           value = app.Switch.Value;
           Flat=app.Flatfoldable.Value;
           %
           phi1Deg = app.phi1.Value;
           phi2Deg = app.phi2.Value;
           m = app.m.Value;
           n = app.n.Value;
           r = app.R.Value;
           d1overr = app.d1R.Value;
          
           
              if phi1Deg*phi2Deg*m*n*r*d1overr~=0
               % %convert angles
               phi1 = phi1Deg*pi/180;
               phi2 = phi2Deg*pi/180;
 
               %Miura or Kresling
               %%%%%Miura
               if strcmp(value, 'Miura')
                   text(app.UIAxes4,0.5,0.5,0.5,'There is no S3 for Miura','HorizontalAlignment','center');                 
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center')
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 1
                       [Node, Panel, V1, Vs, Hs, Rs] = MiuraMonoS1(phi1,phi2,r,n,m,d1overr,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l2=1.1.*axis(app.UIAxes2);
                       axis(app.UIAxes2,l2);
                       zlim(app.UIAxes2,l2(1,5:6));
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;                                                
                           app.Hs.Value=0;
                           [Rs] = MiuraFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel, V1, Vs, Hs, Rs] = MiuraBiS1(phi1,phi2,r,n,m,d1overr,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','g');
                       end
                       [Node, Panel,V2,Vd, Hd, Rd] = MiuraBiS2(phi1,phi2,r,n,m,d1overr,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','g');
                       axis(app.UIAxes2,'equal');
                       axis(app.UIAxes3,'equal');
                       axis(app.UIAxes4,'equal');

                       l3=1.1.*axis(app.UIAxes3);
                       axis(app.UIAxes2,l3);
                       zlim(app.UIAxes2,l3(1,5:6));
                       axis(app.UIAxes3,l3);
                       zlim(app.UIAxes3,l3(1,5:6));
                       axis(app.UIAxes4,l3);
                       zlim(app.UIAxes4,l3(1,5:6));
                                       
                   end


                   %%%%%Kresling
               elseif strcmp(value, 'Kresling')
                   if app.NumberofSolutions.Value == 0
                       text(app.UIAxes2,0.5,0.5,0.5,'There is no S1','HorizontalAlignment','center') ;
                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;
                       text(app.UIAxes4,0.5,0.5,0.5,'There is no S3','HorizontalAlignment','center') ;
                       l2=axis(app.UIAxes2);
                       axis(app.UIAxes3,l2);
                       zlim(app.UIAxes3,l2(1,5:6));
                       axis(app.UIAxes4,l2);
                       zlim(app.UIAxes4,l2(1,5:6));
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 2
                       [Node, Panel,V1,Vs,Hs,Rs] = KreslingMonoS1(phi1,phi2,r,n,m,Output);
                       app.Vs.Value=Vs;
                       app.V1.Value=V1;
                       app.Hs.Value=Hs;
                       app.Rs.Value=Rs;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       text(app.UIAxes3,0.5,0.5,0.5,'There is no S2','HorizontalAlignment','center') ;

                       [Node, Panel,V3,Vd, Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       %%%%%%%%%%
                   elseif app.NumberofSolutions.Value == 3
                       if strcmp(Flat, 'Yes')
                           text(app.UIAxes2,0.5,0.5,0.5,'Flat-foldable','HorizontalAlignment','center')
                           app.Vs.Value=0;
                           app.V1.Value=0;
                           app.Hs.Value=0;
                           [Rs] =KreslingFlatS1(phi1,phi2,r,n,m,d1overr);
                           app.Rs.Value=Rs;
                       else
                           [Node, Panel,V1,Vs,Hs,Rs] = KreslingBiS1(phi1,phi2,r,n,m,Output);
                           app.Vs.Value=Vs;
                           app.V1.Value=V1;
                           app.Hs.Value=Hs;
                           app.Rs.Value=Rs;
                           PlotOri(app.UIAxes2,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       end
                       %
                       [Node, Panel,V2,Vd,Hd, Rd] = KreslingBiS2(phi1,phi2,r,n,m,Output);
                       app.VdS2.Value=Vd;
                       app.V2.Value=V2;
                       app.HdS2.Value=Hd;
                       app.RdS2.Value=Rd;
                       PlotOri(app.UIAxes3,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');

                       [Node, Panel,V3,Vd,Hd, Rd] = KreslingS3(phi1,phi2,r,n,m,Output);
                       app.VdS3.Value=Vd;
                       app.V3.Value=V3;
                       app.HdS3.Value=Hd;
                       app.RdS3.Value=Rd;
                       PlotOri(app.UIAxes4,Node,Panel,[],'PanelColor','[0.67, 0.83, 0.9]/256');
                       app.RdS2Rs.Value=app.RdS2.Value/app.Rs.Value;
                       app.RdS3Rs.Value=app.RdS3.Value/app.Rs.Value;
                       app.VdS3Vs.Value=app.VdS3.Value/app.Vs.Value;
                       app.VdS2Vs.Value=app.VdS2.Value/app.Vs.Value;
                       app.V3V1.Value=app.V3.Value/app.V1.Value;
                       app.V2V1.Value=app.V2.Value/app.V1.Value;
                       app.HdS2Hs.Value=app.HdS2.Value/app.Hs.Value;
                       app.HdS3Hs.Value=app.HdS3.Value/app.Hs.Value;
                   end
                   axis(app.UIAxes2,'equal');
                   axis(app.UIAxes3,'equal');
                   axis(app.UIAxes4,'equal');

                   l4=1.1.*axis(app.UIAxes4);
                   axis(app.UIAxes2,l4);
                   zlim(app.UIAxes2,l4(1,5:6))
                   axis(app.UIAxes3,l4);
                   zlim(app.UIAxes3,l4(1,5:6))
                   axis(app.UIAxes4,l4);
                   zlim(app.UIAxes4,l4(1,5:6))
               end
               axis(app.UIAxes2,'vis3d')
               axis(app.UIAxes3,'vis3d')
               axis(app.UIAxes4,'vis3d')
           else
               msgbox('Invalid input parameters','warn')
              end
        end

        % Value changed function: RdS2Rs
        function RdS2RsValueChanged(app, event)
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, '\phi_1 (\circ)')
            ylabel(app.UIAxes, '\phi_2 (\circ)')
            app.UIAxes.XLim = [0 90];
            app.UIAxes.YLim = [0 90];
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80 90];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'};
            app.UIAxes.YTick = [0 10 20 30 40 50 60 70 80 90];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'};
            app.GeometryandVolumeCalculationforMiuraandKreslingBellowsLabel = uilabel(app.UIFigure);
            app.GeometryandVolumeCalculationforMiuraandKreslingBellowsLabel.Text = 'Geometry and Volume Calculation for Miura and Kresling Bellows';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = '$n$';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.n.ValueChangedFcn = createCallbackFcn(app, @nValueChanged, true);
            app.n.Value = 4;
            app.phi1 = uieditfield(app.UIFigure, 'numeric');
            app.phi1.ValueChangedFcn = createCallbackFcn(app, @phi1ValueChanged, true);
            app.phi1.Value = 75;
            app.phi_2circLabel = uilabel(app.UIFigure);
            app.phi_2circLabel.Interpreter = 'latex';
            app.phi_2circLabel.Text = '$\phi_2 (\circ)$';
            app.phi2 = uieditfield(app.UIFigure, 'numeric');
            app.phi2.ValueChangedFcn = createCallbackFcn(app, @phi2ValueChanged, true);
            app.phi2.Value = 32;
            app.RmLabel = uilabel(app.UIFigure);
            app.RmLabel.Interpreter = 'latex';
            app.RmLabel.Text = '$R~(m)$';
            app.R = uieditfield(app.UIFigure, 'numeric');
            app.R.ValueChangedFcn = createCallbackFcn(app, @RValueChanged, true);
            app.R.Value = 3;
            app.d1R = uieditfield(app.UIFigure, 'numeric');
            app.d1R.ValueChangedFcn = createCallbackFcn(app, @d1RValueChanged, true);
            app.d1R.Value = 1.5707963267949;
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Interpreter = 'latex';
            app.mLabel.Text = '$m$';
            app.m = uieditfield(app.UIFigure, 'numeric');
            app.m.ValueChangedFcn = createCallbackFcn(app, @mValueChanged, true);
            app.m.Value = 2;
            app.NumberofSolutions = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSolutions.ValueChangedFcn = createCallbackFcn(app, @NumberofSolutionsValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'Green: Miura-ori'; 'Blue: Kresling'; ''};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Kresling', 'Miura'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Kresling';
            app.NoteEditFieldLabel = uilabel(app.UIFigure);
            app.NoteEditFieldLabel.Text = 'Note';
            app.Note = uieditfield(app.UIFigure, 'text');
            app.Note.ValueChangingFcn = createCallbackFcn(app, @NoteValueChanging, true);
            app.Note.Value = 'd1/R = 2π/n';
            app.Step1ChoosenandplotdesignspaceLabel = uilabel(app.UIFigure);
            app.Step1ChoosenandplotdesignspaceLabel.Text = 'Step 1: Choose n and plot design space';
            app.Step2SelectacreasepatternLabel = uilabel(app.UIFigure);
            app.Step2SelectacreasepatternLabel.Text = 'Step 2: Select a crease pattern';
            app.Step3ComputenumberofstablestatesLabel = uilabel(app.UIFigure);
            app.Step3ComputenumberofstablestatesLabel.Text = 'Step 3: Compute number of stable states';
            app.Step4OutputgeometryandcomputevolumeLabel = uilabel(app.UIFigure);
            app.Step4OutputgeometryandcomputevolumeLabel.Text = 'Step 4:  Output geometry and compute volume';
            app.lowerbound = uieditfield(app.UIFigure, 'numeric');
            app.lowerbound.ValueChangedFcn = createCallbackFcn(app, @lowerboundValueChanged, true);
            app.upperbound = uieditfield(app.UIFigure, 'numeric');
            app.NumberofstablestatesLabel = uilabel(app.UIFigure);
            app.NumberofstablestatesLabel.Text = 'Number of stable states';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FlatfoldableEditFieldLabel = uilabel(app.UIFigure);
            app.FlatfoldableEditFieldLabel.Text = 'Flat-foldable?';
            app.Flatfoldable = uieditfield(app.UIFigure, 'text');
            app.KreslingLabel = uilabel(app.UIFigure);
            app.KreslingLabel.Text = 'Kresling';
            app.RedTristableLabel = uilabel(app.UIFigure);
            app.RedTristableLabel.Text = 'Red: Tristable';
            app.BlueBistableLabel = uilabel(app.UIFigure);
            app.BlueBistableLabel.Text = 'Blue: Bistable';
            app.MiuraLabel = uilabel(app.UIFigure);
            app.MiuraLabel.Text = 'Miura';
            app.RedBistableLabel = uilabel(app.UIFigure);
            app.RedBistableLabel.Text = 'Red: Bistable';
            app.BlueMonostableLabel = uilabel(app.UIFigure);
            app.BlueMonostableLabel.Text = 'Blue: Monostable';
            app.phi_1circLabel = uilabel(app.UIFigure);
            app.phi_1circLabel.Interpreter = 'latex';
            app.phi_1circLabel.Text = '$\phi_1 (\circ)$';
            app.d_1RLabel = uilabel(app.UIFigure);
            app.d_1RLabel.Interpreter = 'latex';
            app.d_1RLabel.Text = '$d_1/R$';
            app.d_1RSlider = uislider(app.UIFigure);
            app.d_1RSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.d_1RSlider.MajorTickLabels = {''};
            app.d_1RSlider.ValueChangingFcn = createCallbackFcn(app, @d_1RSliderValueChanging, true);
            app.OutputFoldButton = uibutton(app.UIFigure, 'push');
            app.OutputFoldButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFoldButtonPushed, true);
            app.OutputFoldButton.Text = 'Output .Fold';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StableState1Tab = uitab(app.TabGroup);
            app.StableState1Tab.Title = 'Stable State 1';
            app.UIAxes2 = uiaxes(app.StableState1Tab);
            xlabel(app.UIAxes2, 'X (m)')
            ylabel(app.UIAxes2, 'Y (m)')
            zlabel(app.UIAxes2, 'Z (m)')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown2, true);
            app.V_1Label = uilabel(app.StableState1Tab);
            app.V_1Label.Interpreter = 'latex';
            app.V_1Label.Text = '$V_1$';
            app.V1 = uieditfield(app.StableState1Tab, 'numeric');
            app.V_sLabel = uilabel(app.StableState1Tab);
            app.V_sLabel.Interpreter = 'latex';
            app.V_sLabel.Text = '$V_s$';
            app.Vs = uieditfield(app.StableState1Tab, 'numeric');
            app.H_sLabel = uilabel(app.StableState1Tab);
            app.H_sLabel.Interpreter = 'latex';
            app.H_sLabel.Text = '$H_s$';
            app.Hs = uieditfield(app.StableState1Tab, 'numeric');
            app.R_sLabel = uilabel(app.StableState1Tab);
            app.R_sLabel.Interpreter = 'latex';
            app.R_sLabel.Text = '$R_s$';
            app.Rs = uieditfield(app.StableState1Tab, 'numeric');
            app.StableState2Tab = uitab(app.TabGroup);
            app.StableState2Tab.Title = 'Stable State 2';
            app.UIAxes3 = uiaxes(app.StableState2Tab);
            xlabel(app.UIAxes3, 'X (m)')
            ylabel(app.UIAxes3, 'Y (m)')
            zlabel(app.UIAxes3, 'Z (m)')
            app.V_2Label = uilabel(app.StableState2Tab);
            app.V_2Label.Interpreter = 'latex';
            app.V_2Label.Text = '$V_2$';
            app.V2 = uieditfield(app.StableState2Tab, 'numeric');
            app.V_dS_2Label = uilabel(app.StableState2Tab);
            app.V_dS_2Label.Interpreter = 'latex';
            app.V_dS_2Label.Text = '$V_dS_2$';
            app.VdS2 = uieditfield(app.StableState2Tab, 'numeric');
            app.H_dS_2Label = uilabel(app.StableState2Tab);
            app.H_dS_2Label.Interpreter = 'latex';
            app.H_dS_2Label.Text = '$H_dS_2$';
            app.HdS2 = uieditfield(app.StableState2Tab, 'numeric');
            app.R_dS_2Label = uilabel(app.StableState2Tab);
            app.R_dS_2Label.Interpreter = 'latex';
            app.R_dS_2Label.Text = '$R_dS_2$';
            app.RdS2 = uieditfield(app.StableState2Tab, 'numeric');
            app.StableState3Tab = uitab(app.TabGroup);
            app.StableState3Tab.Title = 'Stable State 3';
            app.UIAxes4 = uiaxes(app.StableState3Tab);
            xlabel(app.UIAxes4, 'X (m)')
            ylabel(app.UIAxes4, 'Y (m)')
            zlabel(app.UIAxes4, 'Z (m)')
            app.H_dS_3Label = uilabel(app.StableState3Tab);
            app.H_dS_3Label.Interpreter = 'latex';
            app.H_dS_3Label.Text = '$H_dS_3$';
            app.HdS3 = uieditfield(app.StableState3Tab, 'numeric');
            app.V_3Label = uilabel(app.StableState3Tab);
            app.V_3Label.Interpreter = 'latex';
            app.V_3Label.Text = '$V_3$';
            app.V3 = uieditfield(app.StableState3Tab, 'numeric');
            app.R_dS_3Label = uilabel(app.StableState3Tab);
            app.R_dS_3Label.Interpreter = 'latex';
            app.R_dS_3Label.Text = '$R_dS_3$';
            app.RdS3 = uieditfield(app.StableState3Tab, 'numeric');
            app.V_dS_3Label = uilabel(app.StableState3Tab);
            app.V_dS_3Label.Interpreter = 'latex';
            app.V_dS_3Label.Text = '$V_dS_3$';
            app.VdS3 = uieditfield(app.StableState3Tab, 'numeric');
            app.V_2V_1Label = uilabel(app.UIFigure);
            app.V_2V_1Label.Interpreter = 'latex';
            app.V_2V_1Label.Text = '$V_2/V_1$';
            app.V2V1 = uieditfield(app.UIFigure, 'numeric');
            app.V_3V_1Label = uilabel(app.UIFigure);
            app.V_3V_1Label.Interpreter = 'latex';
            app.V_3V_1Label.Text = '$V_3/V_1$';
            app.V3V1 = uieditfield(app.UIFigure, 'numeric');
            app.R_dS_3R_sLabel = uilabel(app.UIFigure);
            app.R_dS_3R_sLabel.Interpreter = 'latex';
            app.R_dS_3R_sLabel.Text = '$R_dS_3/R_s$';
            app.RdS3Rs = uieditfield(app.UIFigure, 'numeric');
            app.V_dS_2V_sLabel = uilabel(app.UIFigure);
            app.V_dS_2V_sLabel.Interpreter = 'latex';
            app.V_dS_2V_sLabel.Text = '$V_dS_2/V_s$';
            app.VdS2Vs = uieditfield(app.UIFigure, 'numeric');
            app.V_dS_3V_sLabel = uilabel(app.UIFigure);
            app.V_dS_3V_sLabel.Interpreter = 'latex';
            app.V_dS_3V_sLabel.Text = '$V_dS_3/V_s$';
            app.VdS3Vs = uieditfield(app.UIFigure, 'numeric');
            app.R_dS_2R_sLabel = uilabel(app.UIFigure);
            app.R_dS_2R_sLabel.Interpreter = 'latex';
            app.R_dS_2R_sLabel.Text = '$R_dS_2/R_s$';
            app.RdS2Rs = uieditfield(app.UIFigure, 'numeric');
            app.RdS2Rs.ValueChangedFcn = createCallbackFcn(app, @RdS2RsValueChanged, true);
            app.H_dS_3H_sLabel = uilabel(app.UIFigure);
            app.H_dS_3H_sLabel.Interpreter = 'latex';
            app.H_dS_3H_sLabel.Text = '$H_dS_3/H_s$';
            app.HdS3Hs = uieditfield(app.UIFigure, 'numeric');
            app.H_dS_2H_sLabel = uilabel(app.UIFigure);
            app.H_dS_2H_sLabel.Interpreter = 'latex';
            app.H_dS_2H_sLabel.Text = '$H_dS_2/H_s$';
            app.HdS2Hs = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Geometry_and_Volume_Calculation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end